classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LensSimulationMMGroupProjectUIFigure  matlab.ui.Figure
        LensshapeListBoxLabel           matlab.ui.control.Label
        LensshapeListBox                matlab.ui.control.ListBox
        FirstScreenScalingFactorSliderLabel  matlab.ui.control.Label
        FirstScreenScalingFactorSlider  matlab.ui.control.Slider
        SecondScreenScalingFactorSliderLabel  matlab.ui.control.Label
        SecondScreenScalingFactorSlider  matlab.ui.control.Slider
        DistancebetweenScreensSliderLabel  matlab.ui.control.Label
        DistancebetweenScreensSlider    matlab.ui.control.Slider
        RunSimulationButton             matlab.ui.control.Button
        LensSimulationsLabel            matlab.ui.control.Label
        RandomImageSwitchLabel          matlab.ui.control.Label
        RandomImageSwitch               matlab.ui.control.Switch
        xpositionEditFieldLabel         matlab.ui.control.Label
        xpositionEditField              matlab.ui.control.NumericEditField
        ypositionEditFieldLabel         matlab.ui.control.Label
        ypositionEditField              matlab.ui.control.NumericEditField
        zpositionEditFieldLabel         matlab.ui.control.Label
        zpositionEditField              matlab.ui.control.NumericEditField
        EllipsoidRadiusEditFieldLabel   matlab.ui.control.Label
        EllipsoidRadiusEditField        matlab.ui.control.NumericEditField
        TorusOuterRadiusEditFieldLabel  matlab.ui.control.Label
        TorusOuterRadiusEditField       matlab.ui.control.NumericEditField
        TorusInnerRadiusEditFieldLabel  matlab.ui.control.Label
        TorusInnerRadiusEditField       matlab.ui.control.NumericEditField
        EllipsoidxStretchSquishFactorSpinnerLabel  matlab.ui.control.Label
        EllipsoidxStretchSquishFactorSpinner  matlab.ui.control.Spinner
        VisualizeConfigurationSwitchLabel  matlab.ui.control.Label
        VisualizeConfigurationSwitch    matlab.ui.control.Switch
        FirstScreenxCoordinateSliderLabel  matlab.ui.control.Label
        FirstScreenxCoordinateSlider    matlab.ui.control.Slider
        MediumsorroundingLensDropDownLabel  matlab.ui.control.Label
        MediumsorroundingLensDropDown   matlab.ui.control.DropDown
        MaterialtheLensisMadeofDropDownLabel  matlab.ui.control.Label
        MaterialtheLensisMadeofDropDown  matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Callback function: DistancebetweenScreensSlider, 
        % EllipsoidRadiusEditField, 
        % EllipsoidxStretchSquishFactorSpinner, 
        % FirstScreenScalingFactorSlider, FirstScreenxCoordinateSlider, 
        % LensshapeListBox, MaterialtheLensisMadeofDropDown, 
        % MediumsorroundingLensDropDown, RandomImageSwitch, 
        % RunSimulationButton, SecondScreenScalingFactorSlider, 
        % TorusInnerRadiusEditField, TorusOuterRadiusEditField, 
        % VisualizeConfigurationSwitch, xpositionEditField, 
        % ypositionEditField, zpositionEditField
        function LensshapeListBoxValueChanged(app, event)
            shape = app.LensshapeListBox.Value;
            firstX = app.FirstScreenxCoordinateSlider.Value;
            firstScaling = app.FirstScreenScalingFactorSlider.Value;
            secondScaling = app.SecondScreenScalingFactorSlider.Value;
            distanceScreens = app.DistancebetweenScreensSlider.Value;
            randomImage = app.RandomImageSwitch.Value;
            surMedium = app.MediumsorroundingLensDropDown.Value;
            lensMaterial = app.MaterialtheLensisMadeofDropDown.Value;
            visualize = app.VisualizeConfigurationSwitch.Value;
            ellRad = app.EllipsoidRadiusEditField.Value;
            x = app.xpositionEditField.Value;
            y = app.ypositionEditField.Value;
            z = app.zpositionEditField.Value;
            sx = app.EllipsoidxStretchSquishFactorSpinner.Value;
            R = app.TorusOuterRadiusEditField.Value;
            r = app.TorusInnerRadiusEditField.Value;
            
            
            eventName = event.EventName;
            if strcmp(eventName, 'ButtonPushed')
                multEnt = 0;
                rand = 0;
                if strcmp(randomImage, 'Yes')
                    rand = 1;
                end
                
                vis = 0;
                if strcmp(visualize, 'Yes')
                    vis = 1;
                end
                
                lensMat = 0;
                switch lensMaterial
                    case 'Vacuum'
                        lensMat = 1;
                    case 'Air'
                        lensMat = 2;
                    case 'Glass'
                        lensMat = 3;
                    case 'Water'
                        lensMat = 4;
                    case 'Ice'
                        lensMat = 5;
                    case 'Diamond'
                        lensMat = 6;
                end
                
                medium = 0;
                switch surMedium
                    case 'Vacuum'
                        medium = 1;
                    case 'Air'
                        medium = 2;
                    case 'Glass'
                        medium = 3;
                    case 'Water'
                        medium = 4;
                    case 'Ice'
                        medium = 5;
                    case 'Diamond'
                        medium = 6;
                end
                
                switch shape
                    case 'Ellipsoid'
                        cd('lens_equations');
                        fh = make_ellipsoid([x, y, z], ellRad, [sx, 1, 1]);
                        cd('..');
                    case 'Torus'
                        cd('lens_equations');
                        fh = make_torus([x, y, z], R, r);
                        cd('..');
                end
                
                run_simulation(fh, firstX, firstScaling, secondScaling, distanceScreens, rand, medium, lensMat, vis, multEnt);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LensSimulationMMGroupProjectUIFigure = uifigure;
            app.LensSimulationMMGroupProjectUIFigure.Name = 'Lens Simulation (MM Group Project)';
            app.LensshapeListBoxLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.LensshapeListBoxLabel.Text = 'Lens shape';
            app.LensshapeListBox = uilistbox(app.LensSimulationMMGroupProjectUIFigure);
            app.LensshapeListBox.Items = {'Ellipsoid', 'Torus', 'Heart'};
            app.LensshapeListBox.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.LensshapeListBox.Value = 'Ellipsoid';
            app.FirstScreenScalingFactorSliderLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.FirstScreenScalingFactorSliderLabel.Text = 'First Screen Scaling Factor';
            app.FirstScreenScalingFactorSlider = uislider(app.LensSimulationMMGroupProjectUIFigure);
            app.FirstScreenScalingFactorSlider.Limits = [0.1 10];
            app.FirstScreenScalingFactorSlider.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.FirstScreenScalingFactorSlider.Value = 1;
            app.SecondScreenScalingFactorSliderLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.SecondScreenScalingFactorSliderLabel.Text = 'Second Screen Scaling Factor';
            app.SecondScreenScalingFactorSlider = uislider(app.LensSimulationMMGroupProjectUIFigure);
            app.SecondScreenScalingFactorSlider.Limits = [0.1 10];
            app.SecondScreenScalingFactorSlider.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.SecondScreenScalingFactorSlider.Value = 2;
            app.DistancebetweenScreensSliderLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.DistancebetweenScreensSliderLabel.Text = 'Distance between Screens';
            app.DistancebetweenScreensSlider = uislider(app.LensSimulationMMGroupProjectUIFigure);
            app.DistancebetweenScreensSlider.Limits = [0.1 20];
            app.DistancebetweenScreensSlider.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.DistancebetweenScreensSlider.Value = 2.9;
            app.RunSimulationButton = uibutton(app.LensSimulationMMGroupProjectUIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.LensSimulationsLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.LensSimulationsLabel.Text = 'Lens Simulations';
            app.RandomImageSwitchLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.RandomImageSwitchLabel.Text = 'Random Image?';
            app.RandomImageSwitch = uiswitch(app.LensSimulationMMGroupProjectUIFigure, 'slider');
            app.RandomImageSwitch.Items = {'Yes', 'No'};
            app.RandomImageSwitch.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.RandomImageSwitch.Value = 'Yes';
            app.xpositionEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.xpositionEditFieldLabel.Text = 'x position';
            app.xpositionEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.xpositionEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.xpositionEditField.Value = 2.5;
            app.ypositionEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.ypositionEditFieldLabel.Text = 'y position';
            app.ypositionEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.ypositionEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.zpositionEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.zpositionEditFieldLabel.Text = 'z position';
            app.zpositionEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.zpositionEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.zpositionEditField.Value = 1;
            app.EllipsoidRadiusEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.EllipsoidRadiusEditFieldLabel.Text = 'Ellipsoid Radius';
            app.EllipsoidRadiusEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.EllipsoidRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.EllipsoidRadiusEditField.Value = 0.8;
            app.TorusOuterRadiusEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.TorusOuterRadiusEditFieldLabel.Text = 'Torus Outer Radius';
            app.TorusOuterRadiusEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.TorusOuterRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.TorusOuterRadiusEditField.Value = 1;
            app.TorusInnerRadiusEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.TorusInnerRadiusEditFieldLabel.Text = 'Torus Inner Radius';
            app.TorusInnerRadiusEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.TorusInnerRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.TorusInnerRadiusEditField.Value = 0.5;
            app.EllipsoidxStretchSquishFactorSpinnerLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.EllipsoidxStretchSquishFactorSpinnerLabel.Text = 'Ellipsoid x Stretch/Squish Factor';
            app.EllipsoidxStretchSquishFactorSpinner = uispinner(app.LensSimulationMMGroupProjectUIFigure);
            app.EllipsoidxStretchSquishFactorSpinner.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.EllipsoidxStretchSquishFactorSpinner.Value = 0.8;
            app.VisualizeConfigurationSwitchLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.VisualizeConfigurationSwitchLabel.Text = 'Visualize Configuration?';
            app.VisualizeConfigurationSwitch = uiswitch(app.LensSimulationMMGroupProjectUIFigure, 'slider');
            app.VisualizeConfigurationSwitch.Items = {'Yes', 'No'};
            app.VisualizeConfigurationSwitch.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.VisualizeConfigurationSwitch.Value = 'No';
            app.FirstScreenxCoordinateSliderLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.FirstScreenxCoordinateSliderLabel.Text = 'First Screen x Coordinate';
            app.FirstScreenxCoordinateSlider = uislider(app.LensSimulationMMGroupProjectUIFigure);
            app.FirstScreenxCoordinateSlider.Limits = [0.1 5];
            app.FirstScreenxCoordinateSlider.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.FirstScreenxCoordinateSlider.Value = 1;
            app.MediumsorroundingLensDropDownLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.MediumsorroundingLensDropDownLabel.Text = 'Medium sorrounding Lens';
            app.MediumsorroundingLensDropDown = uidropdown(app.LensSimulationMMGroupProjectUIFigure);
            app.MediumsorroundingLensDropDown.Items = {'Vacuum', 'Air', 'Glass', 'Water', 'Ice', 'Diamond'};
            app.MediumsorroundingLensDropDown.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.MediumsorroundingLensDropDown.Value = 'Air';
            app.MaterialtheLensisMadeofDropDownLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.MaterialtheLensisMadeofDropDownLabel.Text = 'Material the Lens is Made of';
            app.MaterialtheLensisMadeofDropDown = uidropdown(app.LensSimulationMMGroupProjectUIFigure);
            app.MaterialtheLensisMadeofDropDown.Items = {'Vacuum', 'Air', 'Glass', 'Water', 'Ice', 'Diamond'};
            app.MaterialtheLensisMadeofDropDown.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.MaterialtheLensisMadeofDropDown.Value = 'Glass';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LensSimulationMMGroupProjectUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LensSimulationMMGroupProjectUIFigure)
        end
    end
end