classdef lens_simulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LensSimulationMMGroupProjectUIFigure  matlab.ui.Figure
        LensshapeListBoxLabel           matlab.ui.control.Label
        LensshapeListBox                matlab.ui.control.ListBox
        FirstScreenScalingFactorSliderLabel  matlab.ui.control.Label
        FirstScreenScalingFactorSlider  matlab.ui.control.Slider
        SecondScreenScalingFactorSliderLabel  matlab.ui.control.Label
        SecondScreenScalingFactorSlider  matlab.ui.control.Slider
        DistancebetweenScreensSliderLabel  matlab.ui.control.Label
        DistancebetweenScreensSlider    matlab.ui.control.Slider
        RunSimulationButton             matlab.ui.control.Button
        LensSimulationsLabel            matlab.ui.control.Label
        RandomImageSwitchLabel          matlab.ui.control.Label
        RandomImageSwitch               matlab.ui.control.Switch
        xpositionEditFieldLabel         matlab.ui.control.Label
        xpositionEditField              matlab.ui.control.NumericEditField
        ypositionEditFieldLabel         matlab.ui.control.Label
        ypositionEditField              matlab.ui.control.NumericEditField
        zpositionEditFieldLabel         matlab.ui.control.Label
        zpositionEditField              matlab.ui.control.NumericEditField
        EllipsoidRadiusEditFieldLabel   matlab.ui.control.Label
        EllipsoidRadiusEditField        matlab.ui.control.NumericEditField
        TorusOuterRadiusEditFieldLabel  matlab.ui.control.Label
        TorusOuterRadiusEditField       matlab.ui.control.NumericEditField
        TorusInnerRadiusEditFieldLabel  matlab.ui.control.Label
        TorusInnerRadiusEditField       matlab.ui.control.NumericEditField
        xAxisStretchSquishFactorSpinnerLabel  matlab.ui.control.Label
        xAxisStretchSquishFactorSpinner  matlab.ui.control.Spinner
        VisualizeConfigurationSwitchLabel  matlab.ui.control.Label
        VisualizeConfigurationSwitch    matlab.ui.control.Switch
        FirstScreenxCoordinateSliderLabel  matlab.ui.control.Label
        FirstScreenxCoordinateSlider    matlab.ui.control.Slider
        MediumSurroundingLensDropDownLabel  matlab.ui.control.Label
        MediumSurroundingLensDropDown   matlab.ui.control.DropDown
        LensMaterialDropDownLabel       matlab.ui.control.Label
        LensMaterialDropDown            matlab.ui.control.DropDown
        LoadImagebyNameEditFieldLabel   matlab.ui.control.Label
        LoadImagebyNameEditField        matlab.ui.control.EditField
    end

    methods (Access = private)

        % Callback function: DistancebetweenScreensSlider, 
        % EllipsoidRadiusEditField, FirstScreenScalingFactorSlider, 
        % FirstScreenxCoordinateSlider, LensMaterialDropDown, 
        % LensshapeListBox, LoadImagebyNameEditField, 
        % MediumSurroundingLensDropDown, RandomImageSwitch, 
        % RunSimulationButton, SecondScreenScalingFactorSlider, 
        % TorusInnerRadiusEditField, TorusOuterRadiusEditField, 
        % VisualizeConfigurationSwitch, xAxisStretchSquishFactorSpinner, 
        % xpositionEditField, ypositionEditField, zpositionEditField
        function LensshapeListBoxValueChanged(app, event)
            
            % Get values from GUI
            shape = app.LensshapeListBox.Value;
            firstX = app.FirstScreenxCoordinateSlider.Value;
            firstScaling = app.FirstScreenScalingFactorSlider.Value;
            secondScaling = app.SecondScreenScalingFactorSlider.Value;
            distanceScreens = app.DistancebetweenScreensSlider.Value;
            randomImage = app.RandomImageSwitch.Value;
            surMedium = app.MediumSurroundingLensDropDown.Value;
            lensMaterial = app.LensMaterialDropDown.Value;
            visualize = app.VisualizeConfigurationSwitch.Value;
            ellRad = app.EllipsoidRadiusEditField.Value;
            x = app.xpositionEditField.Value;
            y = app.ypositionEditField.Value;
            z = app.zpositionEditField.Value;
            sx = app.xAxisStretchSquishFactorSpinner.Value;
            R = app.TorusOuterRadiusEditField.Value;
            r = app.TorusInnerRadiusEditField.Value;
            
            % Get event name. If 'ButtonPushed', run simulation.
            eventName = event.EventName;
            if strcmp(eventName, 'ButtonPushed')
                % multiple entries possible
                multEnt = 0;
                % random image
                rand = 0;
                if strcmp(randomImage, 'Yes')
                    rand = 1;
                end
                
                % visualize
                vis = 0;
                if strcmp(visualize, 'Yes')
                    vis = 1;
                end
                
                % Set numeric code value for lens material.
                lensMat = 0;
                switch lensMaterial
                    case 'Vacuum'
                        lensMat = 1;
                    case 'Air'
                        lensMat = 2;
                    case 'Glass'
                        lensMat = 3;
                    case 'Water'
                        lensMat = 4;
                    case 'Ice'
                        lensMat = 5;
                    case 'Diamond'
                        lensMat = 6;
                end
                
                % Set numeric code value for medium surrounding the lens.
                medium = 0;
                switch surMedium
                    case 'Vacuum'
                        medium = 1;
                    case 'Air'
                        medium = 2;
                    case 'Glass'
                        medium = 3;
                    case 'Water'
                        medium = 4;
                    case 'Ice'
                        medium = 5;
                    case 'Diamond'
                        medium = 6;
                end
                
                % Make function handle for selected shape.
                switch shape
                    case 'Ellipsoid'
                        cd('lens_equations');
                        fh = make_ellipsoid([x, y, z], ellRad, [sx, 1, 1]);
                        cd('..');
                    case 'Torus'
                        cd('lens_equations');
                        fh = make_torus([x, y, z], R, r);
                        multEnt = 1;
                        cd('..');
                    case 'Heart'
                        cd('lens_equations');
                        fh = make_heart([x, y, z], [sx, 0.8, 1.9 - sx.^(-0.12)]);
                        multEnt = 1;
                        cd('..');
                    case 'Bifolia'
                        cd('lens_equations');
                        fh = make_bifolia([x, y, z], [1, 1, 1]);
                        multEnt = 1;
                        cd('..');
                end
                
                imageName = app.LoadImagebyNameEditField.Value;
                
                % Run simulation
                run_simulation(fh, firstX, firstScaling, secondScaling, distanceScreens, rand, imageName, medium, lensMat, vis, multEnt);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LensSimulationMMGroupProjectUIFigure = uifigure;
            app.LensSimulationMMGroupProjectUIFigure.Name = 'Lens Simulation (MM Group Project)';
            app.LensshapeListBoxLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.LensshapeListBoxLabel.Text = 'Lens shape';
            app.LensshapeListBox = uilistbox(app.LensSimulationMMGroupProjectUIFigure);
            app.LensshapeListBox.Items = {'Ellipsoid', 'Torus', 'Heart', 'Bifolia'};
            app.LensshapeListBox.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.LensshapeListBox.Value = 'Ellipsoid';
            app.FirstScreenScalingFactorSliderLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.FirstScreenScalingFactorSliderLabel.Text = 'First Screen Scaling Factor';
            app.FirstScreenScalingFactorSlider = uislider(app.LensSimulationMMGroupProjectUIFigure);
            app.FirstScreenScalingFactorSlider.Limits = [0 10];
            app.FirstScreenScalingFactorSlider.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.FirstScreenScalingFactorSlider.Value = 1;
            app.SecondScreenScalingFactorSliderLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.SecondScreenScalingFactorSliderLabel.Text = 'Second Screen Scaling Factor';
            app.SecondScreenScalingFactorSlider = uislider(app.LensSimulationMMGroupProjectUIFigure);
            app.SecondScreenScalingFactorSlider.Limits = [0 10];
            app.SecondScreenScalingFactorSlider.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.SecondScreenScalingFactorSlider.Value = 2;
            app.DistancebetweenScreensSliderLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.DistancebetweenScreensSliderLabel.Text = 'Distance between Screens';
            app.DistancebetweenScreensSlider = uislider(app.LensSimulationMMGroupProjectUIFigure);
            app.DistancebetweenScreensSlider.Limits = [0.1 20];
            app.DistancebetweenScreensSlider.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.DistancebetweenScreensSlider.Value = 2.9;
            app.RunSimulationButton = uibutton(app.LensSimulationMMGroupProjectUIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.LensSimulationsLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.LensSimulationsLabel.Text = 'Lens Simulations';
            app.RandomImageSwitchLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.RandomImageSwitchLabel.Text = 'Random Image?';
            app.RandomImageSwitch = uiswitch(app.LensSimulationMMGroupProjectUIFigure, 'slider');
            app.RandomImageSwitch.Items = {'Yes', 'No'};
            app.RandomImageSwitch.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.RandomImageSwitch.Value = 'Yes';
            app.xpositionEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.xpositionEditFieldLabel.Text = 'x position';
            app.xpositionEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.xpositionEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.xpositionEditField.Value = 2.5;
            app.ypositionEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.ypositionEditFieldLabel.Text = 'y position';
            app.ypositionEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.ypositionEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.zpositionEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.zpositionEditFieldLabel.Text = 'z position';
            app.zpositionEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.zpositionEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.zpositionEditField.Value = 1;
            app.EllipsoidRadiusEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.EllipsoidRadiusEditFieldLabel.Text = 'Ellipsoid Radius';
            app.EllipsoidRadiusEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.EllipsoidRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.EllipsoidRadiusEditField.Value = 0.8;
            app.TorusOuterRadiusEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.TorusOuterRadiusEditFieldLabel.Text = 'Torus Outer Radius';
            app.TorusOuterRadiusEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.TorusOuterRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.TorusOuterRadiusEditField.Value = 0.5;
            app.TorusInnerRadiusEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.TorusInnerRadiusEditFieldLabel.Text = 'Torus Inner Radius';
            app.TorusInnerRadiusEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'numeric');
            app.TorusInnerRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.TorusInnerRadiusEditField.Value = 0.1;
            app.xAxisStretchSquishFactorSpinnerLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.xAxisStretchSquishFactorSpinnerLabel.Text = 'x Axis Stretch/Squish Factor';
            app.xAxisStretchSquishFactorSpinner = uispinner(app.LensSimulationMMGroupProjectUIFigure);
            app.xAxisStretchSquishFactorSpinner.Step = 0.1;
            app.xAxisStretchSquishFactorSpinner.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.xAxisStretchSquishFactorSpinner.Value = 0.8;
            app.VisualizeConfigurationSwitchLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.VisualizeConfigurationSwitchLabel.Text = 'Visualize Configuration?';
            app.VisualizeConfigurationSwitch = uiswitch(app.LensSimulationMMGroupProjectUIFigure, 'slider');
            app.VisualizeConfigurationSwitch.Items = {'Yes', 'No'};
            app.VisualizeConfigurationSwitch.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.VisualizeConfigurationSwitch.Value = 'No';
            app.FirstScreenxCoordinateSliderLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.FirstScreenxCoordinateSliderLabel.Text = 'First Screen x Coordinate';
            app.FirstScreenxCoordinateSlider = uislider(app.LensSimulationMMGroupProjectUIFigure);
            app.FirstScreenxCoordinateSlider.Limits = [0.1 5];
            app.FirstScreenxCoordinateSlider.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.FirstScreenxCoordinateSlider.Value = 1;
            app.MediumSurroundingLensDropDownLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.MediumSurroundingLensDropDownLabel.Text = 'Medium Surrounding Lens';
            app.MediumSurroundingLensDropDown = uidropdown(app.LensSimulationMMGroupProjectUIFigure);
            app.MediumSurroundingLensDropDown.Items = {'Vacuum', 'Air', 'Glass', 'Water', 'Ice', 'Diamond'};
            app.MediumSurroundingLensDropDown.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.MediumSurroundingLensDropDown.Value = 'Air';
            app.LensMaterialDropDownLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.LensMaterialDropDownLabel.Text = 'Lens Material';
            app.LensMaterialDropDown = uidropdown(app.LensSimulationMMGroupProjectUIFigure);
            app.LensMaterialDropDown.Items = {'Vacuum', 'Air', 'Glass', 'Water', 'Ice', 'Diamond'};
            app.LensMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
            app.LensMaterialDropDown.Value = 'Glass';
            app.LoadImagebyNameEditFieldLabel = uilabel(app.LensSimulationMMGroupProjectUIFigure);
            app.LoadImagebyNameEditFieldLabel.Text = 'Load Image by Name';
            app.LoadImagebyNameEditField = uieditfield(app.LensSimulationMMGroupProjectUIFigure, 'text');
            app.LoadImagebyNameEditField.ValueChangedFcn = createCallbackFcn(app, @LensshapeListBoxValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = lens_simulations

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LensSimulationMMGroupProjectUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LensSimulationMMGroupProjectUIFigure)
        end
    end
end